classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BichosUIFigure                  matlab.ui.Figure
        Image                           matlab.ui.control.Image
        SeleccioneunorganismoDropDown   matlab.ui.control.DropDown
        SeleccioneunorganismoDropDownLabel  matlab.ui.control.Label
        FamiliaEditField                matlab.ui.control.EditField
        FamiliaEditFieldLabel           matlab.ui.control.Label
        EspecieEditField                matlab.ui.control.EditField
        EspecieEditFieldLabel           matlab.ui.control.Label
        GeneroEditField                 matlab.ui.control.EditField
        GeneroEditFieldLabel            matlab.ui.control.Label
        OrdenEditField                  matlab.ui.control.EditField
        OrdenEditFieldLabel             matlab.ui.control.Label
        ClaseEditField                  matlab.ui.control.EditField
        ClaseEditFieldLabel             matlab.ui.control.Label
        PhyllumEditField                matlab.ui.control.EditField
        PhyllumEditFieldLabel           matlab.ui.control.Label
        ReinoEditField                  matlab.ui.control.EditField
        ReinoEditFieldLabel             matlab.ui.control.Label
        TipoOrganismoEditField          matlab.ui.control.EditField
        CaracteristicasTextArea         matlab.ui.control.TextArea
        CaracteristicasTextAreaLabel    matlab.ui.control.Label
        TipoOrganismoEditFieldLabel     matlab.ui.control.Label
        NombrecientificoEditField       matlab.ui.control.EditField
        NombrecientificoEditFieldLabel  matlab.ui.control.Label
        NombrecomnEditField             matlab.ui.control.EditField
        NombrecomnEditFieldLabel        matlab.ui.control.Label
        CargarinformacinButton          matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarinformacinButton
        function CargarinformacinButtonPushed(app, event)
            main;
            pathToMLAPP = fileparts(mfilename('fullpath'));

            app.CargarinformacinButton.Enable = 'off';

            app.SeleccioneunorganismoDropDown.Items = names;
            app.SeleccioneunorganismoDropDown.Enable = 'on';

            auxBicho = bichos(1);
            app.NombrecomnEditField.Value = auxBicho.nombreComun;
            app.NombrecientificoEditField.Value = auxBicho.nombreCientifico;
            app.CaracteristicasTextArea.Value = auxBicho.caracteristicas;
            app.ClaseEditField.Value = auxBicho.clase;
            app.OrdenEditField.Value = auxBicho.orden;
            app.FamiliaEditField.Value = auxBicho.familia;
            app.EspecieEditField.Value = auxBicho.especie;
            app.PhyllumEditField.Value = auxBicho.phyllum;
            app.ReinoEditField.Value = auxBicho.reino;
            app.TipoOrganismoEditField.Value = auxBicho.tipoOrganismo;
            app.GeneroEditField.Value = auxBicho.genero;
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', auxBicho.imagen);

            auxTiempo = linspace(1,numel(auxBicho.tiempoDeVida),numel(auxBicho.tiempoDeVida));
            A = cell2mat(auxBicho.tiempoDeVida);

            plot(app.UIAxes,auxTiempo, A);
        end

        % Value changed function: SeleccioneunorganismoDropDown
        function SeleccioneunorganismoListBoxValueChanged(app, event)
            main;
            pathToMLAPP = fileparts(mfilename('fullpath'));

            value = app.SeleccioneunorganismoDropDown.Value;
            n = numel(bichos);
            for i = 1:n
                if(bichos(i).compareName(value))
                    auxBicho = bichos(i);
                    break;
                end
            end

            app.NombrecomnEditField.Value = auxBicho.nombreComun;
            app.NombrecientificoEditField.Value = auxBicho.nombreCientifico;
            app.CaracteristicasTextArea.Value = auxBicho.caracteristicas;
            app.ClaseEditField.Value = auxBicho.clase;
            app.OrdenEditField.Value = auxBicho.orden;
            app.FamiliaEditField.Value = auxBicho.familia;
            app.EspecieEditField.Value = auxBicho.especie;
            app.PhyllumEditField.Value = auxBicho.phyllum;
            app.ReinoEditField.Value = auxBicho.reino;
            app.TipoOrganismoEditField.Value = auxBicho.tipoOrganismo;
            app.GeneroEditField.Value = auxBicho.genero;
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', auxBicho.imagen);

            auxTiempo = linspace(1,numel(auxBicho.tiempoDeVida),numel(auxBicho.tiempoDeVida));
            A = cell2mat(auxBicho.tiempoDeVida);

            plot(app.UIAxes,auxTiempo, A);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BichosUIFigure = uifigure('Visible', 'off');
            app.BichosUIFigure.Name = 'Bichos';
            app.BichosUIFigure.Icon = fullfile(pathToMLAPP, 'img', 'hitmontop.jpg');
            app.UIAxes = uiaxes(app.BichosUIFigure);
            title(app.UIAxes, 'Grafica de crecimiento')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CargarinformacinButton = uibutton(app.BichosUIFigure, 'push');
            app.CargarinformacinButton.ButtonPushedFcn = createCallbackFcn(app, @CargarinformacinButtonPushed, true);
            app.CargarinformacinButton.Text = 'Cargar información';
            app.NombrecomnEditFieldLabel = uilabel(app.BichosUIFigure);
            app.NombrecomnEditFieldLabel.Text = 'Nombre común';
            app.NombrecomnEditField = uieditfield(app.BichosUIFigure, 'text');
            app.NombrecomnEditField.Editable = 'off';
            app.NombrecientificoEditFieldLabel = uilabel(app.BichosUIFigure);
            app.NombrecientificoEditFieldLabel.Text = 'Nombre cientifico';
            app.NombrecientificoEditField = uieditfield(app.BichosUIFigure, 'text');
            app.NombrecientificoEditField.Editable = 'off';
            app.TipoOrganismoEditFieldLabel = uilabel(app.BichosUIFigure);
            app.TipoOrganismoEditFieldLabel.Text = 'Tipo Organismo';
            app.CaracteristicasTextAreaLabel = uilabel(app.BichosUIFigure);
            app.CaracteristicasTextAreaLabel.Text = 'Caracteristicas';
            app.CaracteristicasTextArea = uitextarea(app.BichosUIFigure);
            app.CaracteristicasTextArea.Editable = 'off';
            app.TipoOrganismoEditField = uieditfield(app.BichosUIFigure, 'text');
            app.TipoOrganismoEditField.Editable = 'off';
            app.ReinoEditFieldLabel = uilabel(app.BichosUIFigure);
            app.ReinoEditFieldLabel.Text = 'Reino';
            app.ReinoEditField = uieditfield(app.BichosUIFigure, 'text');
            app.ReinoEditField.Editable = 'off';
            app.PhyllumEditFieldLabel = uilabel(app.BichosUIFigure);
            app.PhyllumEditFieldLabel.Text = 'Phyllum';
            app.PhyllumEditField = uieditfield(app.BichosUIFigure, 'text');
            app.PhyllumEditField.Editable = 'off';
            app.ClaseEditFieldLabel = uilabel(app.BichosUIFigure);
            app.ClaseEditFieldLabel.Text = 'Clase';
            app.ClaseEditField = uieditfield(app.BichosUIFigure, 'text');
            app.ClaseEditField.Editable = 'off';
            app.OrdenEditFieldLabel = uilabel(app.BichosUIFigure);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.OrdenEditField = uieditfield(app.BichosUIFigure, 'text');
            app.OrdenEditField.Editable = 'off';
            app.GeneroEditFieldLabel = uilabel(app.BichosUIFigure);
            app.GeneroEditFieldLabel.Text = 'Genero';
            app.GeneroEditField = uieditfield(app.BichosUIFigure, 'text');
            app.GeneroEditField.Editable = 'off';
            app.EspecieEditFieldLabel = uilabel(app.BichosUIFigure);
            app.EspecieEditFieldLabel.Text = 'Especie';
            app.EspecieEditField = uieditfield(app.BichosUIFigure, 'text');
            app.EspecieEditField.Editable = 'off';
            app.FamiliaEditFieldLabel = uilabel(app.BichosUIFigure);
            app.FamiliaEditFieldLabel.Text = 'Familia';
            app.FamiliaEditField = uieditfield(app.BichosUIFigure, 'text');
            app.FamiliaEditField.Editable = 'off';
            app.SeleccioneunorganismoDropDownLabel = uilabel(app.BichosUIFigure);
            app.SeleccioneunorganismoDropDownLabel.Text = 'Seleccione un organismo';
            app.SeleccioneunorganismoDropDown = uidropdown(app.BichosUIFigure);
            app.SeleccioneunorganismoDropDown.Items = {};
            app.SeleccioneunorganismoDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccioneunorganismoListBoxValueChanged, true);
            app.SeleccioneunorganismoDropDown.Enable = 'off';
            app.SeleccioneunorganismoDropDown.Value = {};
            app.Image = uiimage(app.BichosUIFigure);
            % Show the figure after all components are created
            app.BichosUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BichosUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BichosUIFigure)
        end
    end
end